classdef GeneradorSignalDanielM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ControlAmplitudKnob             matlab.ui.control.Knob
        ControlAmplitudKnobLabel        matlab.ui.control.Label
        DanielFelipeMartnezAlzateLabel  matlab.ui.control.Label
        PhaseSelector                   matlab.ui.control.DropDown
        PhaseLabel                      matlab.ui.control.Label
        GENERARButton                   matlab.ui.control.Button
        CANTPERIODOSEditField           matlab.ui.control.NumericEditField
        CANTPERIODOSEditFieldLabel      matlab.ui.control.Label
        FRECUENCIAEditField             matlab.ui.control.NumericEditField
        FRECUENCIAEditFieldLabel        matlab.ui.control.Label
        AMPLITUDEditField               matlab.ui.control.NumericEditField
        AMPLITUDEditFieldLabel          matlab.ui.control.Label
        GENERADORDESEALESLabel          matlab.ui.control.Label
        sierr                           matlab.ui.control.UIAxes
        triang                          matlab.ui.control.UIAxes
        cuadr                           matlab.ui.control.UIAxes
        sino                            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GENERARButton
        function GENERARButtonPushed(app, event)
                        
clc, format compact
%------------- INPUT -------------------

A =app.AMPLITUDEditField.Value ;      %Volts
f = app.FRECUENCIAEditField.Value;    %Hertz
phi = str2num(app.PhaseSelector.Value);     %radians
cantper = app.CANTPERIODOSEditField.Value;%cantidad periodos a visualizar
compfreq = 10; %componentes de freq (series Fourier)
%------------- PROCESS -------------------
%Construcciòn Señal Sinusoidal
T = 1/f;
t = linspace(0,cantper*T,1000);
xt = A*sin(2*pi*f*t + phi);
%Construcciòn Señal Cuadrada (Serie Fourier)
cuadrada = 0;
for n=1:2:(compfreq*2-1)
    cuadrada = cuadrada + ((4*A)/(n*pi))*sin(2*pi*n*f*t);
end
%Construcciòn Señal Triangular (Serie Fourier)
triangular = 0;
for n=1:2:(compfreq*2-1)
    triangular = triangular + ((8*A)/((n^2)*(pi^2)))*cos(2*pi*n*f*t);
end
%Construcciòn Señal Diente Sierra (Serie Fourier)
sierra = 0;
for n=1:(compfreq)
    sierra = sierra + ((-1)^(n+1))*((2*A)/(n*pi))*sin(2*pi*n*f*t);
end
%------------- OUTPUT -------------------
plot(app.sino,t,xt), title('Señal Sinusoidal');
plot(app.cuadr,t,cuadrada), title('Señal Cuadrada');
plot(app.triang,t,triangular), title('Señal Triangular');
plot(app.sierr,t,sierra), title('Señal Diente Sierra');

        end

        % Value changed function: ControlAmplitudKnob
        function ControlAmplitudKnobValueChanged(app, event)
            value = app.ControlAmplitudKnob.Value;
            app.AMPLITUDEditField.Value=value
            GENERARButtonPushed(app, event)
        end

        % Value changed function: AMPLITUDEditField
        function AMPLITUDEditFieldValueChanged(app, event)
            value = app.AMPLITUDEditField.Value;
            app.ControlAmplitudKnob.Value=value
            GENERARButtonPushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sino = uiaxes(app.UIFigure);
            title(app.sino, 'Señal Sinusoidal')
            xlabel(app.sino, 'X')
            ylabel(app.sino, 'Y')
            zlabel(app.sino, 'Z')
            app.cuadr = uiaxes(app.UIFigure);
            title(app.cuadr, 'Señal Cuadrada')
            xlabel(app.cuadr, 'X')
            ylabel(app.cuadr, 'Y')
            zlabel(app.cuadr, 'Z')
            app.triang = uiaxes(app.UIFigure);
            title(app.triang, 'Señal Diente Sierra')
            xlabel(app.triang, 'X')
            ylabel(app.triang, 'Y')
            zlabel(app.triang, 'Z')
            app.sierr = uiaxes(app.UIFigure);
            title(app.sierr, 'Señal Triangular')
            xlabel(app.sierr, 'X')
            ylabel(app.sierr, 'Y')
            zlabel(app.sierr, 'Z')
            app.GENERADORDESEALESLabel = uilabel(app.UIFigure);
            app.GENERADORDESEALESLabel.Text = 'GENERADOR DE SEÑALES';
            app.AMPLITUDEditFieldLabel = uilabel(app.UIFigure);
            app.AMPLITUDEditFieldLabel.Text = 'AMPLITUD';
            app.AMPLITUDEditField = uieditfield(app.UIFigure, 'numeric');
            app.AMPLITUDEditField.ValueChangedFcn = createCallbackFcn(app, @AMPLITUDEditFieldValueChanged, true);
            app.AMPLITUDEditField.Value = 10;
            app.FRECUENCIAEditFieldLabel = uilabel(app.UIFigure);
            app.FRECUENCIAEditFieldLabel.Text = 'FRECUENCIA';
            app.FRECUENCIAEditField = uieditfield(app.UIFigure, 'numeric');
            app.FRECUENCIAEditField.Value = 3400;
            app.CANTPERIODOSEditFieldLabel = uilabel(app.UIFigure);
            app.CANTPERIODOSEditFieldLabel.Text = 'CANT PERIODOS';
            app.CANTPERIODOSEditField = uieditfield(app.UIFigure, 'numeric');
            app.CANTPERIODOSEditField.Value = 2;
            app.GENERARButton = uibutton(app.UIFigure, 'push');
            app.GENERARButton.ButtonPushedFcn = createCallbackFcn(app, @GENERARButtonPushed, true);
            app.GENERARButton.Text = 'GENERAR';
            app.PhaseLabel = uilabel(app.UIFigure);
            app.PhaseLabel.Text = 'Phase';
            app.PhaseSelector = uidropdown(app.UIFigure);
            app.PhaseSelector.Items = {'0', 'pi/2', 'pi', '3*pi/2'};
            app.PhaseSelector.Value = '0';
            app.DanielFelipeMartnezAlzateLabel = uilabel(app.UIFigure);
            app.DanielFelipeMartnezAlzateLabel.Text = 'Daniel Felipe Martínez Alzate';
            app.ControlAmplitudKnobLabel = uilabel(app.UIFigure);
            app.ControlAmplitudKnobLabel.Text = 'Control Amplitud';
            app.ControlAmplitudKnob = uiknob(app.UIFigure, 'continuous');
            app.ControlAmplitudKnob.ValueChangedFcn = createCallbackFcn(app, @ControlAmplitudKnobValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeneradorSignalDanielM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end