classdef ModDig_DanielMartinez < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BITSEditField                matlab.ui.control.EditField
        BITSEditFieldLabel           matlab.ui.control.Label
        AMPLITUDCARRIERKnob          matlab.ui.control.Knob
        AMPLITUDCARRIERKnobLabel     matlab.ui.control.Label
        TBEditField                  matlab.ui.control.NumericEditField
        TBEditFieldLabel             matlab.ui.control.Label
        PhaseSelector                matlab.ui.control.DropDown
        PhaseLabel                   matlab.ui.control.Label
        FRECUENCIAEditField          matlab.ui.control.NumericEditField
        FRECUENCIAEditFieldLabel     matlab.ui.control.Label
        AMPLITUDEditField            matlab.ui.control.NumericEditField
        AMPLITUDEditFieldLabel       matlab.ui.control.Label
        IRAMODULACIONANALGICAButton  matlab.ui.control.Button
        e3Label                      matlab.ui.control.Label
        GENERARButton                matlab.ui.control.Button
        MODULACINDIGITALLabel        matlab.ui.control.Label
        DATOSENTRADABITSLabel        matlab.ui.control.Label
        DATOSCARRIERLabel            matlab.ui.control.Label
        ask                          matlab.ui.control.UIAxes
        bits                         matlab.ui.control.UIAxes
        carrier                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GENERARButton
        function GENERARButtonPushed(app, event)
            clc; format compact
%----------------- INPUT -------------------
%Datos del carrier
Ac = app.AMPLITUDEditField.Value;      %Volts
fc = (app.FRECUENCIAEditField.Value)*1000;    %Hertz
phi_c = str2num(app.PhaseSelector.Value);     %radians
%datos de entrada DATOS (bits)
bits = app.BITSEditField.Value;
tb = app.TBEditField.Value;
%----------------- PROCESS -------------------
%Construcción Carrier
cantper_c = length(bits)*2;
Tc = 1/fc;
tc = linspace(0,cantper_c*Tc,length(bits)*tb);
carrier = Ac*sin(2*pi*fc*tc + phi_c);
%construir cadena bits
uno = ones(1,tb);
cero = zeros(1,tb);
cadena = [];
for n=1:length(bits)
    if(bits(n)=='1')
        cadena = [cadena uno];
    else
        cadena = [cadena cero];
    end
end
bitstream = 5*cadena;

%modulación ASK (desplazamiento de Amplitud)
xask = bitstream.*carrier;

%----------------- OUTPUT -------------------
plot(app.carrier,tc,carrier),title('Carrier/Portadora')
%axis([ xi xf yi yf ])
axis([ 0 cantper_c*Tc -Ac*5  Ac*5 ])

plot(app.bits,bitstream),title('DATOS (bits)')
%axis([ xi xf yi yf ])
axis([ 0 length(bitstream) -Ac*5  Ac*5 ])


plot(app.ask,xask),title('Señal ASK')
%axis([ xi xf yi yf ])
axis([ 0 length(xask) -Ac*5  Ac*5 ])
        end

        % Button pushed function: IRAMODULACIONANALGICAButton
        function IRAMODULACIONANALGICAButtonPushed(app, event)
            modAnDig_DanielMartinez;
        end

        % Value changed function: AMPLITUDCARRIERKnob
        function AMPLITUDCARRIERKnobValueChanged(app, event)
            value = app.AMPLITUDCARRIERKnob.Value;
            app.AMPLITUDEditField.Value=value;
            GENERARButtonPushed(app, event)
        end

        % Value changed function: AMPLITUDEditField
        function AMPLITUDEditFieldValueChanged(app, event)
            value = app.AMPLITUDEditField.Value;
            app.AMPLITUDCARRIERKnob.Value=value;
            GENERARButtonPushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.carrier = uiaxes(app.UIFigure);
            title(app.carrier, 'Carrier/Portadora')
            xlabel(app.carrier, 'X')
            ylabel(app.carrier, 'Y')
            zlabel(app.carrier, 'Z')
            app.bits = uiaxes(app.UIFigure);
            title(app.bits, 'Datos(bits)')
            xlabel(app.bits, 'X')
            ylabel(app.bits, 'Y')
            zlabel(app.bits, 'Z')
            app.ask = uiaxes(app.UIFigure);
            title(app.ask, 'Señal ASK')
            xlabel(app.ask, 'X')
            ylabel(app.ask, 'Y')
            zlabel(app.ask, 'Z')
            app.DATOSCARRIERLabel = uilabel(app.UIFigure);
            app.DATOSCARRIERLabel.Text = 'DATOS CARRIER';
            app.DATOSENTRADABITSLabel = uilabel(app.UIFigure);
            app.DATOSENTRADABITSLabel.Text = 'DATOS ENTRADA (BITS)';
            app.MODULACINDIGITALLabel = uilabel(app.UIFigure);
            app.MODULACINDIGITALLabel.Text = 'MODULACIÓN DIGITAL';
            app.GENERARButton = uibutton(app.UIFigure, 'push');
            app.GENERARButton.ButtonPushedFcn = createCallbackFcn(app, @GENERARButtonPushed, true);
            app.GENERARButton.Text = 'GENERAR';
            app.e3Label = uilabel(app.UIFigure);
            app.e3Label.Text = '*e3';
            app.IRAMODULACIONANALGICAButton = uibutton(app.UIFigure, 'push');
            app.IRAMODULACIONANALGICAButton.ButtonPushedFcn = createCallbackFcn(app, @IRAMODULACIONANALGICAButtonPushed, true);
            app.IRAMODULACIONANALGICAButton.Text = 'IR A MODULACION ANALÓGICA';
            app.AMPLITUDEditFieldLabel = uilabel(app.UIFigure);
            app.AMPLITUDEditFieldLabel.Text = 'AMPLITUD';
            app.AMPLITUDEditField = uieditfield(app.UIFigure, 'numeric');
            app.AMPLITUDEditField.ValueChangedFcn = createCallbackFcn(app, @AMPLITUDEditFieldValueChanged, true);
            app.AMPLITUDEditField.Value = 10;
            app.FRECUENCIAEditFieldLabel = uilabel(app.UIFigure);
            app.FRECUENCIAEditFieldLabel.Text = 'FRECUENCIA';
            app.FRECUENCIAEditField = uieditfield(app.UIFigure, 'numeric');
            app.FRECUENCIAEditField.Value = 1000;
            app.PhaseLabel = uilabel(app.UIFigure);
            app.PhaseLabel.Text = 'Phase';
            app.PhaseSelector = uidropdown(app.UIFigure);
            app.PhaseSelector.Items = {'0', 'pi/2', 'pi', '3*pi/2'};
            app.PhaseSelector.Value = '0';
            app.TBEditFieldLabel = uilabel(app.UIFigure);
            app.TBEditFieldLabel.Text = 'TB';
            app.TBEditField = uieditfield(app.UIFigure, 'numeric');
            app.TBEditField.Value = 50;
            app.AMPLITUDCARRIERKnobLabel = uilabel(app.UIFigure);
            app.AMPLITUDCARRIERKnobLabel.Text = 'AMPLITUD CARRIER';
            app.AMPLITUDCARRIERKnob = uiknob(app.UIFigure, 'continuous');
            app.AMPLITUDCARRIERKnob.Limits = [0 50];
            app.AMPLITUDCARRIERKnob.ValueChangedFcn = createCallbackFcn(app, @AMPLITUDCARRIERKnobValueChanged, true);
            app.BITSEditFieldLabel = uilabel(app.UIFigure);
            app.BITSEditFieldLabel.Text = 'BITS';
            app.BITSEditField = uieditfield(app.UIFigure, 'text');
            app.BITSEditField.Value = '00110100010';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModDig_DanielMartinez

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end