classdef modAnDig_DanielMartinez < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        IRAMODULACINDIGITALButton     matlab.ui.control.Button
        e3Label                       matlab.ui.control.Label
        X2Label                       matlab.ui.control.Label
        X1Label                       matlab.ui.control.Label
        GENERARButton                 matlab.ui.control.Button
        MODULACINANALGICALabel        matlab.ui.control.Label
        PhaseSelector_2               matlab.ui.control.DropDown
        PhaseLabel_2                  matlab.ui.control.Label
        CANTPERIODOSEditField_2       matlab.ui.control.NumericEditField
        CANTPERIODOSEditField_2Label  matlab.ui.control.Label
        FRECUENCIAEditField_2         matlab.ui.control.NumericEditField
        FRECUENCIAEditField_2Label    matlab.ui.control.Label
        AMPLITUDEditField_2           matlab.ui.control.NumericEditField
        AMPLITUDEditField_2Label      matlab.ui.control.Label
        PhaseSelector                 matlab.ui.control.DropDown
        PhaseLabel                    matlab.ui.control.Label
        DATOSANALGICOSLabel           matlab.ui.control.Label
        DATOSCARRIERLabel             matlab.ui.control.Label
        MODULACINFMEditField          matlab.ui.control.NumericEditField
        MODULACINFMEditFieldLabel     matlab.ui.control.Label
        MODULACINAMEditField          matlab.ui.control.NumericEditField
        MODULACINAMEditFieldLabel     matlab.ui.control.Label
        CANTPERIODOSEditField         matlab.ui.control.NumericEditField
        CANTPERIODOSEditFieldLabel    matlab.ui.control.Label
        FRECUENCIAEditField           matlab.ui.control.NumericEditField
        FRECUENCIAEditFieldLabel      matlab.ui.control.Label
        AMPLITUDEditField             matlab.ui.control.NumericEditField
        AMPLITUDEditFieldLabel        matlab.ui.control.Label
        pm                            matlab.ui.control.UIAxes
        fm                            matlab.ui.control.UIAxes
        am                            matlab.ui.control.UIAxes
        datosalogic                   matlab.ui.control.UIAxes
        carrier                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GENERARButton
        function GENERARButtonPushed(app, event)
clc,format compact
%------------- INPUT -------------------
%DATOS CARRIER
Ac = app.AMPLITUDEditField.Value;      %Volts
fc = (app.FRECUENCIAEditField.Value)*1000;  %Hertz
phi_c =  str2num(app.PhaseSelector.Value);   %radians
cantper_c = app.CANTPERIODOSEditField.Value;%cantidad periodos a visualizar
%DATOS ANALOGICOS
Ax = app.AMPLITUDEditField_2.Value;      %Volts
fx = app.FRECUENCIAEditField_2.Value;    %Hertz
phi_x = str2num(app.PhaseSelector_2.Value);   %radians
cantper_x = app.CANTPERIODOSEditField_2.Value;%cantidad periodos a visualizar
%indice modulacion AM: 0<m<=1
m=app.MODULACINAMEditField.Value;
%indice modulacion PM: n>=2
n=app.MODULACINFMEditField.Value;
%--------------- PROCESS ----------------------
%CONSTRUCCION CARRIER
Tc=1/fc;
tc=linspace(0,cantper_c*Tc,1000);
carrier = Ac*sin(2*pi*fc*tc+phi_c);
%DATOS ANALOGICOS
Tx=1/fx;
tx=linspace(0,cantper_x*Tx,1000);
xt = Ax*sin(2*pi*fx*tx+phi_x);
%modulacion AM
xam=(1+m*xt).*carrier;
%modulacion FM
xfm=Ac*sin(2*pi*fc*tc+n*xt);
%modulacion PM
xt_diff = Ax*sin(2*pi*fx*tx+(phi_x+pi/2));
xpm=Ac*sin(2*pi*fc*tc+n*xt);
%--------------- OUTPUT ----------------------
%GRAFICA CARRIER
plot(app.carrier,tc,carrier),title('Carrier/Portadora')
plot(app.datosalogic,tx,xt),title('Datos Analogicos')
plot(app.am,tc,xam),title('Señal AM')
plot(app.fm,tc,xfm),title('Señal FM')
plot(app.pm,tc,xpm),title('Señal PM')

        end

        % Button pushed function: IRAMODULACINDIGITALButton
        function IRAMODULACINDIGITALButtonPushed(app, event)
            ModDig_DanielMartinez;
        end

        % Callback function
        function AMPLITUDCARRIERKnobValueChanged(app, event)

        end

        % Callback function
        function AMPLITUDANALGICOSKnobValueChanged(app, event)

        end

        % Value changed function: AMPLITUDEditField
        function AMPLITUDEditFieldValueChanged(app, event)

        end

        % Value changed function: AMPLITUDEditField_2
        function AMPLITUDEditField_2ValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.carrier = uiaxes(app.UIFigure);
            title(app.carrier, 'Carrier/Portadora')
            xlabel(app.carrier, 'X')
            ylabel(app.carrier, 'Y')
            zlabel(app.carrier, 'Z')
            app.datosalogic = uiaxes(app.UIFigure);
            title(app.datosalogic, 'Datos Analogicos')
            xlabel(app.datosalogic, 'X')
            ylabel(app.datosalogic, 'Y')
            zlabel(app.datosalogic, 'Z')
            app.am = uiaxes(app.UIFigure);
            title(app.am, 'Señal AM')
            xlabel(app.am, 'X')
            ylabel(app.am, 'Y')
            zlabel(app.am, 'Z')
            app.fm = uiaxes(app.UIFigure);
            title(app.fm, 'Señal FM')
            xlabel(app.fm, 'X')
            ylabel(app.fm, 'Y')
            zlabel(app.fm, 'Z')
            app.pm = uiaxes(app.UIFigure);
            title(app.pm, 'Señal PM')
            xlabel(app.pm, 'X')
            ylabel(app.pm, 'Y')
            zlabel(app.pm, 'Z')
            app.AMPLITUDEditFieldLabel = uilabel(app.UIFigure);
            app.AMPLITUDEditFieldLabel.Text = 'AMPLITUD';
            app.AMPLITUDEditField = uieditfield(app.UIFigure, 'numeric');
            app.AMPLITUDEditField.ValueChangedFcn = createCallbackFcn(app, @AMPLITUDEditFieldValueChanged, true);
            app.AMPLITUDEditField.Value = 10;
            app.FRECUENCIAEditFieldLabel = uilabel(app.UIFigure);
            app.FRECUENCIAEditFieldLabel.Text = 'FRECUENCIA';
            app.FRECUENCIAEditField = uieditfield(app.UIFigure, 'numeric');
            app.FRECUENCIAEditField.Value = 1000;
            app.CANTPERIODOSEditFieldLabel = uilabel(app.UIFigure);
            app.CANTPERIODOSEditFieldLabel.Text = 'CANT PERIODOS';
            app.CANTPERIODOSEditField = uieditfield(app.UIFigure, 'numeric');
            app.CANTPERIODOSEditField.Value = 25;
            app.MODULACINAMEditFieldLabel = uilabel(app.UIFigure);
            app.MODULACINAMEditFieldLabel.Text = 'MODULACIÓN AM';
            app.MODULACINAMEditField = uieditfield(app.UIFigure, 'numeric');
            app.MODULACINAMEditField.Value = 0.4;
            app.MODULACINFMEditFieldLabel = uilabel(app.UIFigure);
            app.MODULACINFMEditFieldLabel.Text = 'MODULACIÓN FM';
            app.MODULACINFMEditField = uieditfield(app.UIFigure, 'numeric');
            app.MODULACINFMEditField.Value = 2;
            app.DATOSCARRIERLabel = uilabel(app.UIFigure);
            app.DATOSCARRIERLabel.Text = 'DATOS CARRIER';
            app.DATOSANALGICOSLabel = uilabel(app.UIFigure);
            app.DATOSANALGICOSLabel.Text = 'DATOS ANALÓGICOS';
            app.PhaseLabel = uilabel(app.UIFigure);
            app.PhaseLabel.Text = 'Phase';
            app.PhaseSelector = uidropdown(app.UIFigure);
            app.PhaseSelector.Items = {'0', 'pi/2', 'pi', '3*pi/2'};
            app.PhaseSelector.Value = '0';
            app.AMPLITUDEditField_2Label = uilabel(app.UIFigure);
            app.AMPLITUDEditField_2Label.Text = 'AMPLITUD';
            app.AMPLITUDEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AMPLITUDEditField_2.ValueChangedFcn = createCallbackFcn(app, @AMPLITUDEditField_2ValueChanged, true);
            app.AMPLITUDEditField_2.Value = 10;
            app.FRECUENCIAEditField_2Label = uilabel(app.UIFigure);
            app.FRECUENCIAEditField_2Label.Text = 'FRECUENCIA';
            app.FRECUENCIAEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FRECUENCIAEditField_2.Value = 3400;
            app.CANTPERIODOSEditField_2Label = uilabel(app.UIFigure);
            app.CANTPERIODOSEditField_2Label.Text = 'CANT PERIODOS';
            app.CANTPERIODOSEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CANTPERIODOSEditField_2.Value = 2;
            app.PhaseLabel_2 = uilabel(app.UIFigure);
            app.PhaseLabel_2.Text = 'Phase';
            app.PhaseSelector_2 = uidropdown(app.UIFigure);
            app.PhaseSelector_2.Items = {'0', 'pi/2', 'pi', '3*pi/2'};
            app.PhaseSelector_2.Value = '0';
            app.MODULACINANALGICALabel = uilabel(app.UIFigure);
            app.MODULACINANALGICALabel.Text = 'MODULACIÓN ANALÓGICA';
            app.GENERARButton = uibutton(app.UIFigure, 'push');
            app.GENERARButton.ButtonPushedFcn = createCallbackFcn(app, @GENERARButtonPushed, true);
            app.GENERARButton.Text = 'GENERAR';
            app.X1Label = uilabel(app.UIFigure);
            app.X1Label.Text = '0 < X <= 1';
            app.X2Label = uilabel(app.UIFigure);
            app.X2Label.Text = 'X >=2';
            app.e3Label = uilabel(app.UIFigure);
            app.e3Label.Text = '*e3';
            app.IRAMODULACINDIGITALButton = uibutton(app.UIFigure, 'push');
            app.IRAMODULACINDIGITALButton.ButtonPushedFcn = createCallbackFcn(app, @IRAMODULACINDIGITALButtonPushed, true);
            app.IRAMODULACINDIGITALButton.Text = 'IR A MODULACIÓN DIGITAL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modAnDig_DanielMartinez

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end