classdef seriefouriercDanielM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DanielFelipeMartnezAlzateLabel  matlab.ui.control.Label
        GENERARButton                   matlab.ui.control.Button
        AMPLITUDKnob                    matlab.ui.control.Knob
        AMPLITUDKnobLabel               matlab.ui.control.Label
        SERIEDEFOURIERCUADRADALabel     matlab.ui.control.Label
        CANTPERIODOSEditField           matlab.ui.control.NumericEditField
        CANTPERIODOSEditFieldLabel      matlab.ui.control.Label
        FRECUENCIAEditField             matlab.ui.control.NumericEditField
        FRECUENCIAEditFieldLabel        matlab.ui.control.Label
        AMPLITUDEditField               matlab.ui.control.NumericEditField
        AMPLITUDEditFieldLabel          matlab.ui.control.Label
        TF4                             matlab.ui.control.UIAxes
        TF3                             matlab.ui.control.UIAxes
        TF2                             matlab.ui.control.UIAxes
        TF1                             matlab.ui.control.UIAxes
        SF3                             matlab.ui.control.UIAxes
        SF4                             matlab.ui.control.UIAxes
        SF2                             matlab.ui.control.UIAxes
        SF1                             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AMPLITUDKnob
        function AMPLITUDKnobValueChanged(app, event)
            value = app.AMPLITUDKnob.Value;
            app.AMPLITUDEditField.Value=value
            GENERARButtonPushed(app, event)
            
        end

        % Button pushed function: GENERARButton
        function GENERARButtonPushed(app, event)
                            clc,format compact
                %--------------- INPUT ----------------------
                A = app.AMPLITUDEditField.Value; 
                f = app.FRECUENCIAEditField.Value;
                cp = app.CANTPERIODOSEditField.Value;
                %--------------- PROCESS ----------------------
                T = 1/f;
                t = linspace(0,cp*T,1000);
                cf1 = (A/1)*sin(2*pi*1*f*t);
                cf2 = (A/3)*sin(2*pi*3*f*t);
                cf3 = (A/5)*sin(2*pi*5*f*t);
                cf4 = (A/7)*sin(2*pi*7*f*t);
                cuadrada_1cf = cf1;
                cuadrada_2cf = cf1 + cf2;
                cuadrada_3cf = cf1 + cf2 + cf3;
                cuadrada_4cf = cf1 + cf2 + cf3 + cf4;
                % transformada de Fourier
                tf_1cf = abs(fft(cuadrada_1cf));
                tf_2cf = abs(fft(cuadrada_2cf));
                tf_3cf = abs(fft(cuadrada_3cf));
                tf_4cf = abs(fft(cuadrada_4cf));
                %--------------- OUTPUT ----------------------
                
                plot(app.SF1,cuadrada_1cf),title('serie fourier: 1 cf')
                plot(app.SF2,t,cuadrada_2cf),title('serie fourier: 2 cf')
                plot(app.SF3,t,cuadrada_3cf),title('serie fourier: 3 cf')
                plot(app.SF4,t,cuadrada_4cf),title('serie fourier: 4 cf')
                
                stem(app.TF1,tf_1cf(1:20)),title('trasformada de fourier: 1 cf')
                stem(app.TF2,tf_2cf(1:20)),title('trasformada de fourier: 2 cf')
                plot(app.TF3,tf_3cf(1:20)),title('trasformada de fourier: 3 cf')
                plot(app.TF4,tf_4cf(1:20)),title('trasformada de fourier: 4 cf')
        end

        % Value changed function: AMPLITUDEditField
        function AMPLITUDEditFieldValueChanged(app, event)
            value = app.AMPLITUDEditField.Value;
            app.AMPLITUDKnob.Value = value
            GENERARButtonPushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SF1 = uiaxes(app.UIFigure);
            title(app.SF1, 'serie fourier: 1 cf')
            xlabel(app.SF1, 'X')
            ylabel(app.SF1, 'Y')
            zlabel(app.SF1, 'Z')
            app.SF2 = uiaxes(app.UIFigure);
            title(app.SF2, 'serie fourier: 2 cf')
            xlabel(app.SF2, 'X')
            ylabel(app.SF2, 'Y')
            zlabel(app.SF2, 'Z')
            app.SF4 = uiaxes(app.UIFigure);
            title(app.SF4, 'serie fourier: 4 cf')
            xlabel(app.SF4, 'X')
            ylabel(app.SF4, 'Y')
            zlabel(app.SF4, 'Z')
            app.SF3 = uiaxes(app.UIFigure);
            title(app.SF3, 'serie fourier: 3 cf')
            xlabel(app.SF3, 'X')
            ylabel(app.SF3, 'Y')
            zlabel(app.SF3, 'Z')
            app.TF1 = uiaxes(app.UIFigure);
            title(app.TF1, 'trasformada de fourier: 1 cf')
            xlabel(app.TF1, 'X')
            ylabel(app.TF1, 'Y')
            zlabel(app.TF1, 'Z')
            app.TF2 = uiaxes(app.UIFigure);
            title(app.TF2, 'trasformada de fourier: 2 cf')
            xlabel(app.TF2, 'X')
            ylabel(app.TF2, 'Y')
            zlabel(app.TF2, 'Z')
            app.TF3 = uiaxes(app.UIFigure);
            title(app.TF3, 'trasformada de fourier: 3 cf')
            xlabel(app.TF3, 'X')
            ylabel(app.TF3, 'Y')
            zlabel(app.TF3, 'Z')
            app.TF4 = uiaxes(app.UIFigure);
            title(app.TF4, 'trasformada de fourier: 4 cf')
            xlabel(app.TF4, 'X')
            ylabel(app.TF4, 'Y')
            zlabel(app.TF4, 'Z')
            app.AMPLITUDEditFieldLabel = uilabel(app.UIFigure);
            app.AMPLITUDEditFieldLabel.Text = 'AMPLITUD';
            app.AMPLITUDEditField = uieditfield(app.UIFigure, 'numeric');
            app.AMPLITUDEditField.ValueChangedFcn = createCallbackFcn(app, @AMPLITUDEditFieldValueChanged, true);
            app.AMPLITUDEditField.Value = 10;
            app.FRECUENCIAEditFieldLabel = uilabel(app.UIFigure);
            app.FRECUENCIAEditFieldLabel.Text = 'FRECUENCIA';
            app.FRECUENCIAEditField = uieditfield(app.UIFigure, 'numeric');
            app.FRECUENCIAEditField.Value = 3400;
            app.CANTPERIODOSEditFieldLabel = uilabel(app.UIFigure);
            app.CANTPERIODOSEditFieldLabel.Text = 'CANT PERIODOS';
            app.CANTPERIODOSEditField = uieditfield(app.UIFigure, 'numeric');
            app.CANTPERIODOSEditField.Value = 1;
            app.SERIEDEFOURIERCUADRADALabel = uilabel(app.UIFigure);
            app.SERIEDEFOURIERCUADRADALabel.Text = 'SERIE DE FOURIER CUADRADA';
            app.AMPLITUDKnobLabel = uilabel(app.UIFigure);
            app.AMPLITUDKnobLabel.Text = 'AMPLITUD';
            app.AMPLITUDKnob = uiknob(app.UIFigure, 'continuous');
            app.AMPLITUDKnob.Limits = [0 50];
            app.AMPLITUDKnob.ValueChangedFcn = createCallbackFcn(app, @AMPLITUDKnobValueChanged, true);
            app.AMPLITUDKnob.Value = 10;
            app.GENERARButton = uibutton(app.UIFigure, 'push');
            app.GENERARButton.ButtonPushedFcn = createCallbackFcn(app, @GENERARButtonPushed, true);
            app.GENERARButton.Text = 'GENERAR';
            app.DanielFelipeMartnezAlzateLabel = uilabel(app.UIFigure);
            app.DanielFelipeMartnezAlzateLabel.Text = 'Daniel Felipe Martínez Alzate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seriefouriercDanielM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end