classdef MartinezDaniel_ClassWorkApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TRANSMITTERSLabel             matlab.ui.control.Label
        REGLAUNNRZCEROEditField       matlab.ui.control.NumericEditField
        REGLAUNNRZCEROEditFieldLabel  matlab.ui.control.Label
        BITSEditField                 matlab.ui.control.EditField
        BITSEditFieldLabel            matlab.ui.control.Label
        REGLAPOLARNRZALTOEditField    matlab.ui.control.NumericEditField
        REGLAPOLARNRZALTOEditFieldLabel  matlab.ui.control.Label
        REGLAUPNRZCEROEditField       matlab.ui.control.NumericEditField
        REGLAUPNRZCEROEditFieldLabel  matlab.ui.control.Label
        REGLABITALTOEditField         matlab.ui.control.NumericEditField
        REGLABITALTOEditFieldLabel    matlab.ui.control.Label
        TBEditField                   matlab.ui.control.NumericEditField
        TBEditFieldLabel              matlab.ui.control.Label
        AMPLITUDEditField             matlab.ui.control.NumericEditField
        AMPLITUDEditFieldLabel        matlab.ui.control.Label
        CODIFICARButton               matlab.ui.control.Button
        polarnrz                      matlab.ui.control.UIAxes
        upsnrz                        matlab.ui.control.UIAxes
        unnrz                         matlab.ui.control.UIAxes
        datosbits                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CODIFICARButton
        function CODIFICARButtonPushed(app, event)
            clc; format compact
            %----------------- INPUT -------------------
%datos de entrada DATOS (bits)
bits = app.BITSEditField.Value;
tb = app.TBEditField.Value;
regla_bit_alto = app.REGLABITALTOEditField.Value;     % valores: 1 v 0
%REGLAS de codificación
A = app.AMPLITUDEditField.Value;                     %Volts
regla_UP_NRZ_cero = app.REGLAUPNRZCEROEditField.Value;      % valores: 1 v 0
regla_UN_NRZ_cero = app.REGLAUNNRZCEROEditField.Value;      % valores: 1 v 0
regla_Polar_NRZ_alto = app.REGLAPOLARNRZALTOEditField.Value;   % valores: 1 v 0
%----------------- PROCESS -------------------
%construir cadena bits
uno = ones(1,tb);
cero = zeros(1,tb);
cadena = [];
cadenaInversa = [];
polar = [];
polarInversa = [];
for n=1:length(bits)
    if(bits(n)=='1')
        cadena = [cadena uno];
        cadenaInversa = [cadenaInversa cero]; 
        polar = [polar uno];
        polarInversa = [polarInversa -uno];
    else
        cadena = [cadena cero];
        cadenaInversa = [cadenaInversa uno]; 
        polar = [polar -uno];
        polarInversa = [polarInversa uno];
    end
end
%Construcción de los BITS
if (regla_bit_alto==1)
    bitstream = 5*cadena;
else
    bitstream = 5*cadenaInversa;
end
%codificación Unipolar Positiva NRZ
if (regla_UP_NRZ_cero == 0)
    UP_NRZ = A*cadena;
else
    UP_NRZ = A*cadenaInversa;
end
%codificación Unipolar Negativa NRZ
if (regla_UN_NRZ_cero == 0)
    UN_NRZ = -A*cadena;
else
    UN_NRZ = -A*cadenaInversa;
end
%codificación Polar NRZ
if (regla_Polar_NRZ_alto==1)
    Polar_NRZ = A*polar;
else
    Polar_NRZ = A*polarInversa;
end
%codificación Bipolar NRZ
%codificación Manchester
%----------------- OUTPUT -------------------
figure(1)
subplot(2,2,1),
plot(app.datosbits,bitstream),title('DATOS (bits)')
%axis([ xi xf yi yf ])
%axis([ 0 length(bitstream) -1-A  A+1 ])
subplot(2,2,2),
plot(app.upsnrz,UP_NRZ),title('Coding: UP NRZ')
axis([ 0 length(UP_NRZ) -1-A  A+1 ])
subplot(2,2,4),
plot(app.unnrz,UN_NRZ),title('Coding: UN NRZ')
axis([ 0 length(UN_NRZ) -1-A  A+1 ])
subplot(2,2,3),
plot(app.polarnrz,Polar_NRZ),title('Coding: POLAR NRZ')
axis([ 0 length(Polar_NRZ) -1-A  A+1 ])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.datosbits = uiaxes(app.UIFigure);
            title(app.datosbits, 'DATOS (bits)')
            xlabel(app.datosbits, 'X')
            ylabel(app.datosbits, 'Y')
            zlabel(app.datosbits, 'Z')
            app.unnrz = uiaxes(app.UIFigure);
            title(app.unnrz, 'Coding: UN NRZ')
            xlabel(app.unnrz, 'X')
            ylabel(app.unnrz, 'Y')
            zlabel(app.unnrz, 'Z')
            app.upsnrz = uiaxes(app.UIFigure);
            title(app.upsnrz, 'Coding: UP NRZ')
            xlabel(app.upsnrz, 'X')
            ylabel(app.upsnrz, 'Y')
            zlabel(app.upsnrz, 'Z')
            app.polarnrz = uiaxes(app.UIFigure);
            title(app.polarnrz, 'Coding: POLAR NRZ')
            xlabel(app.polarnrz, 'X')
            ylabel(app.polarnrz, 'Y')
            zlabel(app.polarnrz, 'Z')
            app.CODIFICARButton = uibutton(app.UIFigure, 'push');
            app.CODIFICARButton.ButtonPushedFcn = createCallbackFcn(app, @CODIFICARButtonPushed, true);
            app.CODIFICARButton.Text = 'CODIFICAR';
            app.AMPLITUDEditFieldLabel = uilabel(app.UIFigure);
            app.AMPLITUDEditFieldLabel.Text = 'AMPLITUD';
            app.AMPLITUDEditField = uieditfield(app.UIFigure, 'numeric');
            app.AMPLITUDEditField.Value = 10;
            app.TBEditFieldLabel = uilabel(app.UIFigure);
            app.TBEditFieldLabel.Text = 'TB';
            app.TBEditField = uieditfield(app.UIFigure, 'numeric');
            app.TBEditField.Value = 50;
            app.REGLABITALTOEditFieldLabel = uilabel(app.UIFigure);
            app.REGLABITALTOEditFieldLabel.Text = 'REGLA BIT ALTO';
            app.REGLABITALTOEditField = uieditfield(app.UIFigure, 'numeric');
            app.REGLABITALTOEditField.Limits = [0 1];
            app.REGLAUPNRZCEROEditFieldLabel = uilabel(app.UIFigure);
            app.REGLAUPNRZCEROEditFieldLabel.Text = 'REGLA UP NRZ CERO';
            app.REGLAUPNRZCEROEditField = uieditfield(app.UIFigure, 'numeric');
            app.REGLAUPNRZCEROEditField.Limits = [0 1];
            app.REGLAUPNRZCEROEditField.Value = 1;
            app.REGLAPOLARNRZALTOEditFieldLabel = uilabel(app.UIFigure);
            app.REGLAPOLARNRZALTOEditFieldLabel.Text = 'REGLA POLAR NRZ ALTO';
            app.REGLAPOLARNRZALTOEditField = uieditfield(app.UIFigure, 'numeric');
            app.REGLAPOLARNRZALTOEditField.Limits = [0 1];
            app.REGLAPOLARNRZALTOEditField.Value = 1;
            app.BITSEditFieldLabel = uilabel(app.UIFigure);
            app.BITSEditFieldLabel.Text = 'BITS';
            app.BITSEditField = uieditfield(app.UIFigure, 'text');
            app.BITSEditField.Value = '00110100010';
            app.REGLAUNNRZCEROEditFieldLabel = uilabel(app.UIFigure);
            app.REGLAUNNRZCEROEditFieldLabel.Text = 'REGLA UN NRZ CERO';
            app.REGLAUNNRZCEROEditField = uieditfield(app.UIFigure, 'numeric');
            app.REGLAUNNRZCEROEditField.Limits = [0 1];
            app.REGLAUNNRZCEROEditField.Value = 1;
            app.TRANSMITTERSLabel = uilabel(app.UIFigure);
            app.TRANSMITTERSLabel.Text = 'TRANSMITTERS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MartinezDaniel_ClassWorkApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end