classdef CifradosCesarVernam_MartinezDaniel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BIN_ENCRIPTADO                 matlab.ui.control.EditField
        TEXTOENCRIPTADOLabel_2         matlab.ui.control.Label
        TEXTO_ENCRIPTADO               matlab.ui.control.EditField
        TEXTOENCRIPTADOLabel           matlab.ui.control.Label
        CLAVETextArea                  matlab.ui.control.EditField
        CLAVELabel                     matlab.ui.control.Label
        TEXTO_ORIGINAL                 matlab.ui.control.EditField
        SUTEXTOAQUIEditFieldLabel      matlab.ui.control.Label
        ENCRIPTAR                      matlab.ui.control.Button
        metodoenc                      matlab.ui.control.DropDown
        MTODODEENCRIPCINDropDownLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: metodoenc
        function metodoencValueChanged(app, event)
            %ACTIVAR CASILLA CLAVE
            %TRAEMOS EL VALOR ACTUAL DEL DROPBOX
            value = app.metodoenc.Value;
            %SI EL VALOR DEL DROPBOX ES IGUAL A VERNAM, ENTONCES SE
            %ACTIVARÁ EL EDIT FIELD DE CLAVE
            if value == "VERNAM"
                app.CLAVETextArea.Enable = "on";
            else
                %SI NO SE CUMPLE LA CONDICION ESTARÁ DESACTIVADO
                app.CLAVETextArea.Enable = "off";
            end

        end

        % Button pushed function: ENCRIPTAR
        function ENCRIPTARButtonPushed(app, event)
            
            
            clc
            %GUARDAMOS EL TEXTO INGRESADO EN UNA VARIABLE
            textoOri = app.TEXTO_ORIGINAL.Value;
            %PASAMOS ESE TEXTO A CODIGO ASCII CON LA FUNCION DOUBLE
            ascii = double(textoOri);

            % CREAMOS CONDICIÓN PARA CAMBIAR DE CIFRADO DEPENDIENDO DEL
            % VALOR DEL DROPBOX
            
            if app.metodoenc.Value == "CESAR INGLES"           
            % CESAR EN INGLES                
                
                for n=1: length(ascii)
                    %CONDICIONALES PARA QUE LAS ULTIMAS LETRAS EQUIVALGAN A
                    %LAS PRIMERAS 
                    if (ascii(n)==32)
                        ascii(n) = 29;
                    elseif (ascii(n)==120)
                        ascii(n)=double('a')-3;
                    elseif (ascii(n)==121)
                        ascii(n)=double('b')-3;
                    elseif (ascii(n)==122)
                        ascii(n)=double('c')-3;
                    elseif (ascii(n)==88)
                        ascii(n)=double('A')-3;
                    elseif (ascii(n)==89)
                        ascii(n)=double('B')-3;
                    elseif (ascii(n)==90)
                        ascii(n)=double('C')-3;  
                    end
                end
                % A CADA NUMERO SE LE SUMAN 3 POSICIONES
                cesar = ascii + 3;
                % PASAMOS ASCII A TEXTO
                criptograma = char(cesar);
                %LO PASAMOS A CODIGO BINARIO
                bits = dec2bin(ascii + 3)
                % ORDENAMOS LA MATRIZ EN UNA SOLA
                bitstream= reshape(bits',1,[])

                %ASIGNAMOS LOS VAMORES OBTENID0S A SUS RESPECTIVOS CAMPOS
                %DE LA INTERFAZ
                app.TEXTO_ENCRIPTADO.Value = criptograma;
                app.BIN_ENCRIPTADO.Value = bitstream

            elseif (app.metodoenc.Value == "CESAR ESPAÑOL")
                %CESAR EN ESPAÑOL
               
                for n=1: length(ascii)
                    if (ascii(n)==32)
                        ascii(n) = 32-3;
                    elseif (ascii(n)==120)
                        ascii(n)=double('a')-3;
                    elseif (ascii(n)==121)
                        ascii(n)=double('b')-3;
                    elseif (ascii(n)==122)
                        ascii(n)=double('c')-3;
                    elseif (ascii(n)==88)
                        ascii(n)=double('A')-3;
                    elseif (ascii(n)==89)
                        ascii(n)=double('B')-3;
                    elseif (ascii(n)==90)
                        ascii(n)=double('C')-3;
                    %Español
                    %AGREGAMOS LAS CONDICIONES PARA LAS LETRAS EN ESPAÑOL,
                    %PUES SE AGREGA LA Ñ AL ABECEDARIO
                    elseif (ascii(n)==108)%l 
                        ascii(n)=double('ñ')-3;
                    elseif (ascii(n)==109)%m
                        ascii(n)=double('o')-3;
                    elseif (ascii(n)==110)%n
                        ascii(n)=double('p')-3;
                    elseif (ascii(n)==double('ñ'))%ñ
                        ascii(n)=double('q')-3;
                    elseif (ascii(n)==76)%L
                        ascii(n)=double('Ñ')-3;
                    elseif (ascii(n)==77)%M
                        ascii(n)=double('O')-3;
                    elseif (ascii(n)==78)%N
                        ascii(n)=double('P')-3;
                    elseif (ascii(n)==double('Ñ'))%Ñ
                        ascii(n)=double('Q')-3;
                    end
                end
                %MISMO PROCESO QUE CON EL CIFRADO EN INGLES
                cesar = ascii + 3;
                criptograma = char(cesar);
                bits = dec2bin(cesar);
                bitstream = reshape(bits',1,[])
                app.TEXTO_ENCRIPTADO.Value = criptograma;
                app.BIN_ENCRIPTADO.Value = bitstream
                app.TEXTO_ENCRIPTADO.Value = criptograma;


            else
                % CIFRADO VERNAM
                % TRAEMOS EL TEXTO, LO PASAMOS A BINARIO Y LO ORDENAMOS EN
                % UNA FILA
                bits = dec2bin(ascii)
                bitstream = reshape(bits',1,[])
                
                %RECORREMOS LA MATRIZ Y LA GUARDAMOS EN UN ARREGLO CON
                %VALORES NUMERICOS
                arraytexto = [];
                longitud = length(bitstream);
                for x=1 : longitud
                    if (bitstream(x)=='0')
                        arraytexto=[arraytexto 0];
                    elseif (bitstream(x)=='1')
                        arraytexto=[arraytexto 1];
                    end
                end                
                              
                %CREAMOS UNA CLAVE ALEATORIA CON LOS VALORES DE 0 Y 1, CON
                %LA MISMA LONGITUD QUE EL BITSTREAM OBTENIDO ANTERIORMENTE
                clavem = randi([0 1],1,longitud)
                %PASAMOS LA MATRIZ A UN ARREGLO
                clave = mat2str(clavem)
                
                %UNA VEZ TENIDO LOS DOS ARREGLOS RESULTANTES, PODREMOS
                %HACER LA COMPARACION XOR
                result = xor(arraytexto,clavem) 

                %RECORREMOS EL ARREGLO OBTENIDO PARA PASAR LOS VALORES
                %LÓGICOS A  TEXTO

                cifrado = []

                for x=1 : length(result)
                    if (result(x)== 0)
                        cifrado=[cifrado '0'];
                    elseif (result(x)== 1)
                        cifrado=[cifrado '1'];
                    end
                end
                
                %PASAMOS EL BITSTREAM RESULTANTE A CARÁCTERES, RECORRIENDO
                %LA MATRIZ, SEPARANDOLA EN CONJUNTOS DE 7 CARACTERES,
                %PASANDOLAS A CODIGO ASCCI Y LUEGO A CADENA DE TEXTO
                
                a = "";

                if(mod(length(cifrado),6) == 0)
                    a = char(bin2dec(reshape(cifrado,6,[]).')).';
                elseif(mod(length(cifrado),7) == 0)
                    a = char(bin2dec(reshape(cifrado,7,[]).')).';
                elseif(mod(length(cifrado),8) == 0)
                    a = char(bin2dec(reshape(cifrado,8,[]).')).';
                end

                %ASIGNAMOS LOS VALORES EN SU RESPECTIVO CAMPO DE LA
                %INTERFAZ
                app.CLAVETextArea.Value = clave
                app.TEXTO_ENCRIPTADO.Value = a    
                app.BIN_ENCRIPTADO.Value = cifrado
            end


        end

        % Value changing function: TEXTO_ORIGINAL
        function TEXTO_ORIGINALValueChanging(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MTODODEENCRIPCINDropDownLabel = uilabel(app.UIFigure);
            app.MTODODEENCRIPCINDropDownLabel.Text = 'MÉTODO DE ENCRIPCIÓN';
            app.metodoenc = uidropdown(app.UIFigure);
            app.metodoenc.Items = {'CESAR INGLES', 'CESAR ESPAÑOL', 'VERNAM'};
            app.metodoenc.ValueChangedFcn = createCallbackFcn(app, @metodoencValueChanged, true);
            app.metodoenc.Value = 'CESAR INGLES';
            app.ENCRIPTAR = uibutton(app.UIFigure, 'push');
            app.ENCRIPTAR.ButtonPushedFcn = createCallbackFcn(app, @ENCRIPTARButtonPushed, true);
            app.ENCRIPTAR.Text = 'ENCRIPTAR';
            app.SUTEXTOAQUIEditFieldLabel = uilabel(app.UIFigure);
            app.SUTEXTOAQUIEditFieldLabel.Text = 'SU TEXTO AQUI';
            app.TEXTO_ORIGINAL = uieditfield(app.UIFigure, 'text');
            app.TEXTO_ORIGINAL.ValueChangingFcn = createCallbackFcn(app, @TEXTO_ORIGINALValueChanging, true);
            app.CLAVELabel = uilabel(app.UIFigure);
            app.CLAVELabel.Text = 'CLAVE';
            app.CLAVETextArea = uieditfield(app.UIFigure, 'text');
            app.CLAVETextArea.Editable = 'off';
            app.CLAVETextArea.Enable = 'off';
            app.TEXTOENCRIPTADOLabel = uilabel(app.UIFigure);
            app.TEXTOENCRIPTADOLabel.Text = {'TEXTO '; 'ENCRIPTADO'};
            app.TEXTO_ENCRIPTADO = uieditfield(app.UIFigure, 'text');
            app.TEXTO_ENCRIPTADO.Editable = 'off';
            app.TEXTOENCRIPTADOLabel_2 = uilabel(app.UIFigure);
            app.TEXTOENCRIPTADOLabel_2.Text = {'BINARIO '; 'ENCRIPTADO'};
            app.BIN_ENCRIPTADO = uieditfield(app.UIFigure, 'text');
            app.BIN_ENCRIPTADO.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CifradosCesarVernam_MartinezDaniel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end